classdef CCTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ConnectedLamp                   matlab.ui.control.Lamp
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortLabel                    matlab.ui.control.Label
        GraphOptionsLabel               matlab.ui.control.Label
        RefreshListButton               matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        HoldGraphsButton                matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        FlowAxes                        matlab.ui.control.UIAxes
        PressureAxes                    matlab.ui.control.UIAxes
        FlowMonitoringControlPanel      matlab.ui.container.Panel
        Label_9                         matlab.ui.control.Label
        Flow2_LabelField                matlab.ui.control.EditField
        Flow1_LabelField                matlab.ui.control.EditField
        SensorLabel_2                   matlab.ui.control.Label
        GraphLabel_2                    matlab.ui.control.Label
        Flow1_GraphEnable               matlab.ui.control.CheckBox
        Flow2_GraphEnable               matlab.ui.control.CheckBox
        allvaluesinLminLabel            matlab.ui.control.Label
        Flow2_EditField_Current         matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Flow1_EditField_Current         matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        SetpointLabel_2                 matlab.ui.control.Label
        CurrentLabel_2                  matlab.ui.control.Label
        Flow2_SetPoint                  matlab.ui.control.NumericEditField
        Flow1_SetPoint                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        PressureMonitoringControlPanel  matlab.ui.container.Panel
        Pressure3_LabelField            matlab.ui.control.EditField
        Pressure2_LabelField            matlab.ui.control.EditField
        Pressure1_LabelField            matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        Pressure1_GraphEnable           matlab.ui.control.CheckBox
        Pressure2_GraphEnable           matlab.ui.control.CheckBox
        Pressure3_GraphEnable           matlab.ui.control.CheckBox
        GraphLabel                      matlab.ui.control.Label
        SensorLabel                     matlab.ui.control.Label
        allvaluesinmmHgLabel            matlab.ui.control.Label
        Pressure3_EditField_Current     matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        Pressure2_EditField_Current     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Pressure1_EditField_Current     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        SetpointLabel                   matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        Pressure3_SetPoint              matlab.ui.control.NumericEditField
        Pressure2_SetPoint              matlab.ui.control.NumericEditField
        Pressure1_SetPoint              matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        FlowPumpControlPanel            matlab.ui.container.Panel
        ControlSwitch                   matlab.ui.control.Switch
        ControlSwitchLabel              matlab.ui.control.Label
        PumpPowerSlider                 matlab.ui.control.Slider
        PumpPowerSliderLabel            matlab.ui.control.Label
        AnalogWriteValueEditField       matlab.ui.control.NumericEditField
        AnalogWriteValueEditFieldLabel  matlab.ui.control.Label
        OPENVALVEButton                 matlab.ui.control.Button
        PUMPOFFButton                   matlab.ui.control.Button
        PUMPONButton                    matlab.ui.control.Button
        PressureValveControlPanel       matlab.ui.container.Panel
        ClosedSlider                    matlab.ui.control.Slider
        ClosedSliderLabel               matlab.ui.control.Label
        MotorStepNumberEditField        matlab.ui.control.NumericEditField
        MotorStepNumberEditFieldLabel   matlab.ui.control.Label
        SimulateDataCheckBox            matlab.ui.control.CheckBox
    end

    % TODO: Add "refresh ports" button

    properties (Access = private)
        plot_raw_data = true;
        F_REFRESH = 20;  % App refresh rate (Hz)
        ARDUINO_ANALOGWRITE_MAX = 255;

        arduinoObj;
        tempLogFile = fullfile(pwd, 'console_output_temp.txt');  % Define a persistent temporary log file that always stores session history

        t = [];
        flow_vals1 = [];  % Containers for sensor data
        flow_vals2 = [];

        pressure_vals1 = [];
        pressure_vals2 = [];
        pressure_vals3 = [];

        % PID Variables
        DT_PID = 0.1;  % Time step (adjust based on system response)

        Kp = 0;  % No PID control by default
        Ki = 0;
        Kd = 0;

        Kp_flow = 1;  % PID coefficients when controlling flow
        Ki_flow = 0;
        Kd_flow = 0;

        Kp_pressure = 5;  % PID coefficients when controlling pressure
        Ki_pressure = 0;
        Kd_pressure = 0;
        
        pressureMotorPosition = 0;
        MAX_PRESSURE_MOTOR_POSITION = -200*7;  % 7 rotations, 200 steps per rotation; TODO: read in max stepper value from Arduino instead of hard-coding to 7 rotations

        flowDutyCycle = 128;  % Initialize halfway
        PumpControlMode = 'Manual';

        PID_setpoint_id = "None";  % Identifier as to which sensor's setpoint is influencing PID control, since only one sensor at a time can do so
        PID_setpoint = 0;
        PID_output = 0;
        PID_prev_error = 0;
        PID_integral = 0;
        PID_derivative = 0;

        % Legend entry labels
        pressure1_label = "1";
        pressure2_label = "2";
        pressure3_label = "3";

        flow1_label = "1";
        flow2_label = "2";
    end

    properties (Dependent)
        dt_refresh
    end

    methods
        function value = get.dt_refresh(app)
            value = 1 / app.F_REFRESH;  % Compute refresh interval dynamically
        end
    end

    methods (Access = private)

        function [t, newFlowIndicator, flow_value_raw1, flow_value1, ...
                flow_value_raw2, flow_value2, pressure_value_raw1, ...
                pressure_value1, pressure_value_raw2, pressure_value2, ...
                pressure_value_raw3, pressure_value3] = readSensorData(app)

            % Reads and parses serial data from the Arduino serial port.
            % Inputs:
            %   serialObj - MATLAB serial object for Arduino communication.
            % Outputs:
            %   flow_value_raw - Raw ADC value for flow sensor.
            %   flow_value - Processed flow rate in L/min.
            %   pressure_value_raw - Raw ADC value for pressure sensor.
            %   pressure_value - Processed pressure in mmHg.

            if app.SimulateDataCheckBox.Value
                % Simulate data (for testing)
                line = "New Flow?: N, Flow Raw 1: 0, Flow 1: 1.50 L/min, Flow Raw 2: 0, Flow 2: 2.50 L/min, Pressure Raw 1: 1023, Pressure 1: 20 mmHg, Pressure Raw 2: 1023, Pressure 2: 50 mmHg, Pressure Raw 3: 1023, Pressure 3: 80 mmHg\n";
            else
                % Read data from Arduino; flush serial buffer by reading
                % three times to improve results
                %
                % TODO: See if waiting for arduinoObj.NumBytesAvailable > 0
                % replaces the need for this for loop
                for j = 1:3
                    line = readline(app.arduinoObj);
                end
            end

            t = toc;
            pause(app.dt_refresh);  % pause to allow app to process other user inputs (e.g. button presses)

            if isempty(line)
                disp("No serial data received...")
                newFlowIndicator = 'X';   % 'Y' or 'N'
                flow_value_raw1 = -999;
                flow_value1 = -999;
                flow_value_raw2 = -999;
                flow_value2 = -999;
                pressure_value_raw1 = -999;
                pressure_value1 = -999;
                pressure_value_raw2 = -999;
                pressure_value2 = -999;
                pressure_value_raw3 = -999;
                pressure_value3 = -999;
            end

            % Attempt to parse the serial data
            try
                % Expected format:
                % "New Flow?: Y, Flow Raw 1: 120, Flow 1: 3.50 L/min, Flow Raw 2: 110, Flow 2: 3.20 L/min,
                % Pressure Raw 1: 512, Pressure 1: 75.3 mmHg, Pressure Raw 2: 523, Pressure 2: 76.1 mmHg,
                % Pressure Raw 3: 540, Pressure 3: 78.4 mmHg"

                % Extract values using sscanf
                data = sscanf(line, ...
                    ['New Flow?: %c, Flow Raw 1: %d, Flow 1: %f L/min, ' ...
                    'Flow Raw 2: %d, Flow 2: %f L/min, ' ...
                    'Pressure Raw 1: %d, Pressure 1: %f mmHg, ' ...
                    'Pressure Raw 2: %d, Pressure 2: %f mmHg, ' ...
                    'Pressure Raw 3: %d, Pressure 3: %f mmHg, ' ...
                    'Pump Analog Write: %d/255\n']);

                % Ensure we received all expected values
                if numel(data) == 11
                    newFlowIndicator = char(data(1));   % 'Y' or 'N'
                    flow_value_raw1 = data(2);
                    flow_value1 = data(3);
                    flow_value_raw2 = data(4);
                    flow_value2 = data(5);
                    pressure_value_raw1 = data(6);
                    pressure_value1 = data(7);
                    pressure_value_raw2 = data(8);
                    pressure_value2 = data(9);
                    pressure_value_raw3 = data(10);
                    pressure_value3 = data(11);

                    % Get timestamp
                    timestamp = datetime("now");

                    % Format the new sensor data as a string
                    newLogEntry = sprintf(['===== Sensor Data (%s) =====\n', ...
                        'New Flow?: %c\n', ...
                        'Flow Raw 1: %d, Flow 1: %.2f L/min\n', ...
                        'Flow Raw 2: %d, Flow 2: %.2f L/min\n', ...
                        'Pressure Raw 1: %d, Pressure 1: %.2f mmHg\n', ...
                        'Pressure Raw 2: %d, Pressure 2: %.2f mmHg\n', ...
                        'Pressure Raw 3: %d, Pressure 3: %.2f mmHg\n\n'], ...
                        timestamp, newFlowIndicator, ...
                        flow_value_raw1, flow_value1, ...
                        flow_value_raw2, flow_value2, ...
                        pressure_value_raw1, pressure_value1, ...
                        pressure_value_raw2, pressure_value2, ...
                        pressure_value_raw3, pressure_value3);

                    % Print to console
                    fprintf('%s', newLogEntry);
                else
                    warning('Failed to parse expected number of values from serial data.');
                    disp(line);
                end
            catch
                warning('Error reading and parsing serial data.');
                disp(line);
            end

        end

        function startLogging(app)
            % Start recording console output to the temporary file
            diary(app.tempLogFile);
            diary on;

            % Notify user
            fprintf('Logging started. All session logs will be recorded in: %s\n', app.tempLogFile);
        end

        function saveLog(app, filename)
            % Stop logging to the temporary file
            diary off;

            % Copy the temp log file to the new provided log file
            copyfile(app.tempLogFile, filename);

            % Notify user
            fprintf('Log file saved as: %s\n', filename);

            % Restart logging to the temporary file to maintain session history
            diary(app.tempLogFile);
            diary on;
        end

        
        function clearDataArrays(app)
            tic;
            app.t = [];

            app.flow_vals1 = [];  
            app.flow_vals2 = [];

            app.pressure_vals1 = [];
            app.pressure_vals2 = [];
            app.pressure_vals3 = [];

            pause(app.dt_refresh);

            app.runMainLoop();  % Re-run it here from the beginning to reset time array properly
        end
       
        
        function runMainLoop(app)
            while (1)
                % Read sensor data
                [tNew, ~, ~, flow_value1, ~, flow_value2, ...
                    ~, pressure_value1, ~, pressure_value2, ...
                    ~, pressure_value3] = app.readSensorData();

                % Append new sensor data
                app.t = [app.t tNew];
                app.flow_vals1 = [app.flow_vals1 flow_value1];
                app.flow_vals2 = [app.flow_vals2 flow_value2];

                app.pressure_vals1 = [app.pressure_vals1 pressure_value1];
                app.pressure_vals2 = [app.pressure_vals2 pressure_value2];
                app.pressure_vals3 = [app.pressure_vals3 pressure_value3];

                % Update Edit Fields with the latest sensor data
                app.Pressure1_EditField_Current.Value = pressure_value1;
                app.Pressure2_EditField_Current.Value = pressure_value2;
                app.Pressure3_EditField_Current.Value = pressure_value3;

                app.Flow1_EditField_Current.Value = flow_value1;
                app.Flow2_EditField_Current.Value = flow_value2;

                % Plot pressure data based on checkbox selections
                % as long as "hold graph" is not pressed
                if app.HoldGraphsButton.Value == false
                    hold(app.PressureAxes, 'on');
                    cla(app.PressureAxes); % Clear previous plots

                    if app.Pressure1_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals1, 'r', 'DisplayName', app.pressure1_label);
                    end
                    if app.Pressure2_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals2, 'g', 'DisplayName', app.pressure2_label);
                    end
                    if app.Pressure3_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals3, 'b', 'DisplayName', app.pressure3_label);
                    end

                    hold(app.PressureAxes, 'off');
                    title(app.PressureAxes, 'Pressure Data');
                    xlabel(app.PressureAxes, 'Time (s)');
                    ylabel(app.PressureAxes, 'Pressure (mmHg)');
                    ylim(app.PressureAxes, [0 100]);
                    legend(app.PressureAxes, 'show','Location','northwest');

                    % Plot flow data based on checkbox selections
                    hold(app.FlowAxes, 'on');
                    cla(app.FlowAxes); % Clear previous plots

                    if app.Flow1_GraphEnable.Value
                        plot(app.FlowAxes, app.t, app.flow_vals1, 'r', 'DisplayName', app.flow1_label);
                    end
                    if app.Flow2_GraphEnable.Value
                        plot(app.FlowAxes, app.t, app.flow_vals2, 'g', 'DisplayName', app.flow2_label);
                    end

                    hold(app.FlowAxes, 'off');
                    title(app.FlowAxes, 'Flow Data');
                    xlabel(app.FlowAxes, 'Time (s)');
                    ylabel(app.FlowAxes, 'Flow (L/min)');
                    ylim(app.FlowAxes, [0 5]);
                    legend(app.FlowAxes, 'show','Location','northwest');
                end

                % Update PID Outputs (WIP)
                app.updatePIDOutputs(flow_value1);  

                % Check whether disconnect button has been clicked
                if app.ConnectButton.Text == "CONNECT"
                    break
                end

                % Pause to prevent crashes
                pause(app.dt_refresh);
            end
        end

        function setControlMode(app,mode)
            if lower(mode) == "Manual"
                app.PumpControlMode = "Manual";
                app.ControlSwitch.Value = "Manual";
            elseif lower(mode) == "PID"
                app.PumpControlMode = "PID";
                app.ControlSwitch.Value = "PID";
            else
                warning("Invalid control mode selected.");
            end
        end
        
        function sendControlValuesToArduino(app)
            app.MotorStepNumberEditField.Value = app.pressureMotorPosition;

            if lower(app.PumpControlMode) == "PID"
                % Update control values based on PID algorithm
                app.flowDutyCycle = app.flowDutyCycle + round(app.PID_output);
            elseif lower(app.PumpControlMode) == "Manual"
                % do nothing
            else
                error("Invalid pump control mode selected.")
            end

            % Update flow pump control UI
            app.AnalogWriteValueEditField.Value = app.flowDutyCycle;
            app.PumpPowerSlider.Value = app.flowDutyCycle / app.ARDUINO_ANALOGWRITE_MAX * app.PumpPowerSlider.Limits(2);

            % Send command to Arduino
            line = sprintf("Pressure Motor Step Number: %d, Pump Duty Cycle: %d",app.pressureMotorPosition,app.flowDutyCycle);
            app.arduinoObj.writeline(line)
        end

        function setPIDCoefficients(app,inputVariable)
            if inputVariable == "Flow"
                app.Kp = app.Kp_flow;
                app.Ki = app.Ki_flow;
                app.Kd = app.Kd_flow;
            elseif inputVariable == "Pressure"
                app.Kp = app.Kp_pressure;
                app.Ki = app.Ki_pressure;
                app.Kd = app.Kd_pressure;
            else
                app.Kp = 0;
                app.Ki = 0;
                app.Kd = 0;
                disp("Invalid input variable inputted when setting PID coefficients. PID coefficients have been reset to zero.")
            end
        end
        
        function updatePIDOutputs(app, newValue)
            % Calculate PID output
            error = app.PID_setpoint - newValue;
            app.PID_integral = app.PID_integral + error * app.DT_PID;
            app.PID_derivative = (error - app.PID_prev_error) / app.DT_PID;
            app.PID_output = app.Kp * error + app.Ki * app.PID_integral + app.Kd * app.PID_derivative;
            app.PID_prev_error = error;

            % fprintf("Setpoint / New Value / Error / Output / DC: %.2f, %.2f, %.2f, %.2f \n",app.PID_setpoint, newValue, error, app.PID_output, app.flowDutyCycle);

            % Send PID output to Arduino
            app.sendControlValuesToArduino();
        end
        
        function updateSerialPortList(app)
            % Get list of available serial ports
            ports = serialportlist;

            % Read the "friendly names" of the COM Port devices using Powershell

            portInfo = {};

            % Use system command to get device descriptions (Windows)
            if ispc
                [~, cmdout] = system("powershell -Command ""Get-WMIObject Win32_PnPEntity | Where-Object { $_.Name -match '\(COM[0-9]+\)' } | ForEach-Object { $_.Name }""");
                deviceList = splitlines(strtrim(cmdout));
            else
                deviceList = repmat("Unknown Device", size(ports));
            end

            for i = 1:length(ports)
                matchIdx = find(contains(deviceList, ports(i)), 1);
                if ~isempty(matchIdx)
                    idn = deviceList{matchIdx};
                    idn = strtrim(erase(idn, ['(', ports(i), ')'])); % Strip duplicate COM port info from the name
                else
                    idn = "Unknown Device";
                end

                portInfo{i} = sprintf('%s: %s', ports(i), idn);
            end

            % Update dropdown with formatted port names
            app.COMPortDropDown.Items = portInfo;
            app.COMPortDropDown.ItemsData = ports;  % To allow proper connections when using serialport() later off of the selected dropdown value
        end
        
        function setValveControlPanelEnabled(app, value)
            % Enable/disable all pressure valve control panel child objects
            children = app.PressureValveControlPanel.Children;
            for i = 1:numel(children)
                if value == true
                    children(i).Enable = 'on';
                else
                    children(i).Enable = 'off';
                end
            end
        end
        
        function highlightSetPointUI(app, setpointID)
            % Define all setpoints in a struct for easy iteration
            setpoints = struct(...
            'Pressure1', app.Pressure1_SetPoint, ...
            'Pressure2', app.Pressure2_SetPoint, ...
            'Pressure3', app.Pressure3_SetPoint, ...
            'Flow1', app.Flow1_SetPoint, ...
            'Flow2', app.Flow2_SetPoint ...
            );

            % Iterate through all setpoints and update colors
            fieldNames = fieldnames(setpoints);
            for i = 1:length(fieldNames)
                if strcmp(fieldNames{i}, setpointID)
                    setpoints.(fieldNames{i}).BackgroundColor = 'green';
                else
                    setpoints.(fieldNames{i}).BackgroundColor = 'white';
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startLogging();
            app.updateSerialPortList();

            % Other setup
            app.MotorStepNumberEditField.Limits = [app.MAX_PRESSURE_MOTOR_POSITION 0];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if app.ConnectButton.Text == "Connect"
                % Someone just connected to the serial device
                app.ConnectButton.Text = "Disconnect";
                app.ConnectedLamp.Color = "green";

                if app.SimulateDataCheckBox.Value == false
                    app.arduinoObj = serialport(app.COMPortDropDown.Value, 9600);
                    app.setValveControlPanelEnabled(true);
                else
                    app.setValveControlPanelEnabled(false);
                end

                app.setValveControlPanelEnabled(true);
                app.clearDataArrays();
    
                tic;
                app.startLogging();
                app.runMainLoop();

            elseif app.ConnectButton.Text == "Disconnect"
                % Someone just disconnected from the serial device
                app.arduinoObj = [];
                app.setValveControlPanelEnabled(false);
                app.ConnectButton.Text = "Connect";
                app.ConnectedLamp.Color = [0.80,0.80,0.80];
            else
                error("Connect button container contains invalid text.")
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Empty time and sensor data arrays
            app.clearDataArrays();
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.ExportDataButton.Text = "Exporting...";
            app.ExportDataButton.Enable = 'off';

            % Get current timestamp
            timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd-HH-mm-ss'));
            folderName = fullfile(pwd, ['CCTA-', timestamp]);
            
            % Create subfolder
            if ~exist(folderName, 'dir')
                mkdir(folderName);
            end
            
            % Save Pressure and Flow Axes as .fig and .png
            exportgraphics(app.PressureAxes, fullfile(folderName, 'PressurePlot.png'));
            exportgraphics(app.FlowAxes, fullfile(folderName, 'FlowPlot.png'));
            
            % Save data arrays to .mat/.csv files
            data.t = app.t;
            data.flow_vals1 = app.flow_vals1;
            data.flow_vals2 = app.flow_vals2;
            data.pressure_vals1 = app.pressure_vals1;
            data.pressure_vals2 = app.pressure_vals2;
            data.pressure_vals3 = app.pressure_vals3;
            
            save(fullfile(folderName, 'ExportedData.mat'), '-struct', 'data');

            csvData = table( ...
                app.t(:), ...
                app.flow_vals1(:), ...
                app.flow_vals2(:), ...
                app.pressure_vals1(:), ...
                app.pressure_vals2(:), ...
                app.pressure_vals3(:), ...
                'VariableNames', { ...
                'Time (s)', ...
                sprintf('Flow %s (L/min)', app.flow1_label), ...  % use labels from GUI to label the columns
                sprintf('Flow %s (L/min)', app.flow2_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure1_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure2_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure3_label) ...
                } ...
                );
            
            writetable(csvData, fullfile(folderName, 'ExportedData.csv'));

            % Save console output to text file
            app.saveLog(fullfile(folderName, 'console_output.txt'));
            
            % Display dialog box
            uialert(app.UIFigure, ['Data has been saved to ', folderName], 'Export Successful', 'Icon', 'info');

            app.ExportDataButton.Text = "Export Data";
            app.ExportDataButton.Enable = 'on';
        end

        % Value changed function: Pressure1_SetPoint
        function Pressure1_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure1_SetPoint.Value;
            app.setControlMode("PID");
            app.setPIDCoefficients("Pressure");
            app.highlightSetPointUI("Pressure1");
        end

        % Value changed function: MotorStepNumberEditField
        function MotorStepNumberEditFieldValueChanged(app, event)
            value = app.MotorStepNumberEditField.Value;
            app.ClosedSlider.Value = value/app.MAX_PRESSURE_MOTOR_POSITION * app.ClosedSlider.Limits(2);  % update slider

            app.pressureMotorPosition = value;
            app.sendControlValuesToArduino();
        end

        % Value changed function: ClosedSlider
        function ClosedSliderValueChanged(app, event)
            percent_closed = app.ClosedSlider.Value/app.ClosedSlider.Limits(2);  % negative because gear rotates valve the other way
            app.pressureMotorPosition = round(app.MAX_PRESSURE_MOTOR_POSITION * percent_closed);
            app.sendControlValuesToArduino();
        end

        % Button pushed function: RefreshListButton
        function RefreshListButtonPushed(app, event)
            app.updateSerialPortList();
        end

        % Value changed function: PumpPowerSlider
        function PumpPowerSliderValueChanged(app, event)
            app.flowDutyCycle = round(app.PumpPowerSlider.Value/app.PumpPowerSlider.Limits(2)*app.ARDUINO_ANALOGWRITE_MAX);
            app.AnalogWriteValueEditField.Value = app.flowDutyCycle;
            app.setControlMode("Manual");
            app.sendControlValuesToArduino();
        end

        % Value changed function: AnalogWriteValueEditField
        function AnalogWriteValueEditFieldValueChanged(app, event)
            value = app.AnalogWriteValueEditField.Value;
            app.flowDutyCycle = value;
            app.PumpPowerSlider.Value = value/app.ARDUINO_ANALOGWRITE_MAX * app.PumpPowerSlider.Limits(2);  % update slider
            app.setControlMode("Manual");
            app.sendControlValuesToArduino();
        end

        % Value changed function: Pressure1_LabelField
        function Pressure1_LabelFieldValueChanged(app, event)
            app.pressure1_label = app.Pressure1_LabelField.Value;
        end

        % Value changed function: Pressure2_LabelField
        function Pressure2_LabelFieldValueChanged(app, event)
            app.pressure2_label = app.Pressure2_LabelField.Value;
        end

        % Value changed function: Pressure3_LabelField
        function Pressure3_LabelFieldValueChanged(app, event)
            app.pressure3_label = app.Pressure3_LabelField.Value;
        end

        % Value changed function: Flow1_LabelField
        function Flow1_LabelFieldValueChanged(app, event)
            app.flow1_label = app.Flow1_LabelField.Value;
        end

        % Value changed function: Flow2_LabelField
        function Flow2_LabelFieldValueChanged(app, event)
            app.flow2_label = app.Flow2_LabelField.Value;
        end

        % Value changed function: Flow1_SetPoint
        function Flow1_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Flow1_SetPoint.Value;
            app.setControlMode("PID");
            app.setPIDCoefficients("Flow");
            app.highlightSetPointUI("Flow1");
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            app.setControlMode(app.ControlSwitch.Value);
        end

        % Value changed function: Flow2_SetPoint
        function Flow2_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Flow2_SetPoint.Value;
            app.setControlMode("PID");
            app.setPIDCoefficients("Flow");
            app.highlightSetPointUI("Flow2");
        end

        % Value changed function: Pressure2_SetPoint
        function Pressure2_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure2_SetPoint.Value;
            app.setControlMode("PID");
            app.setPIDCoefficients("Pressure");
            app.highlightSetPointUI("Pressure2");
        end

        % Value changed function: Pressure3_SetPoint
        function Pressure3_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure3_SetPoint.Value;
            app.setControlMode("PID");
            app.setPIDCoefficients("Pressure");
            app.highlightSetPointUI("Pressure3");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.PressureMonitoringControlPanel = uipanel(app.MainTab);
            app.PressureMonitoringControlPanel.Title = 'Pressure Monitoring & Control';
            app.Label = uilabel(app.PressureMonitoringControlPanel);
            app.Label.Text = '';
            app.Pressure1_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure1_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure1_SetPointValueChanged, true);
            app.Pressure2_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure2_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure2_SetPointValueChanged, true);
            app.Pressure3_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure3_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure3_SetPointValueChanged, true);
            app.CurrentLabel = uilabel(app.PressureMonitoringControlPanel);
            app.CurrentLabel.Text = 'Current';
            app.SetpointLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SetpointLabel.Text = 'Setpoint';
            app.Label_5 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_5.Text = '1';
            app.Pressure1_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure1_EditField_Current.Editable = 'off';
            app.Label_6 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_6.Text = '2';
            app.Pressure2_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure2_EditField_Current.Editable = 'off';
            app.Label_7 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_7.Text = '3';
            app.Pressure3_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure3_EditField_Current.Editable = 'off';
            app.allvaluesinmmHgLabel = uilabel(app.PressureMonitoringControlPanel);
            app.allvaluesinmmHgLabel.Text = '(all values in mmHg)';
            app.SensorLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SensorLabel.Text = 'Sensor';
            app.GraphLabel = uilabel(app.PressureMonitoringControlPanel);
            app.GraphLabel.Text = 'Graph?';
            app.Pressure3_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure3_GraphEnable.Text = '';
            app.Pressure3_GraphEnable.Value = true;
            app.Pressure2_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure2_GraphEnable.Text = '';
            app.Pressure2_GraphEnable.Value = true;
            app.Pressure1_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure1_GraphEnable.Text = '';
            app.Pressure1_GraphEnable.Value = true;
            app.Label_8 = uilabel(app.PressureMonitoringControlPanel);
            app.Pressure1_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure1_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure1_LabelFieldValueChanged, true);
            app.Pressure1_LabelField.Value = '1';
            app.Pressure2_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure2_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure2_LabelFieldValueChanged, true);
            app.Pressure2_LabelField.Value = '2';
            app.Pressure3_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure3_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure3_LabelFieldValueChanged, true);
            app.Pressure3_LabelField.Value = '3';
            app.FlowMonitoringControlPanel = uipanel(app.MainTab);
            app.FlowMonitoringControlPanel.Title = 'Flow Monitoring & Control';
            app.Label_2 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_2.Text = '';
            app.Flow1_SetPoint = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow1_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Flow1_SetPointValueChanged, true);
            app.Flow2_SetPoint = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow2_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Flow2_SetPointValueChanged, true);
            app.CurrentLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.CurrentLabel_2.Text = 'Current';
            app.SetpointLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.SetpointLabel_2.Text = 'Setpoint';
            app.Label_3 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_3.Text = '1';
            app.Flow1_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow1_EditField_Current.Editable = 'off';
            app.Label_4 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_4.Text = '2';
            app.Flow2_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow2_EditField_Current.Editable = 'off';
            app.allvaluesinLminLabel = uilabel(app.FlowMonitoringControlPanel);
            app.allvaluesinLminLabel.Text = '(all values in L/min)';
            app.Flow2_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow2_GraphEnable.Text = '';
            app.Flow2_GraphEnable.Value = true;
            app.Flow1_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow1_GraphEnable.Text = '';
            app.Flow1_GraphEnable.Value = true;
            app.GraphLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.GraphLabel_2.Text = 'Graph?';
            app.SensorLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.SensorLabel_2.Text = 'Sensor';
            app.Flow1_LabelField = uieditfield(app.FlowMonitoringControlPanel, 'text');
            app.Flow1_LabelField.ValueChangedFcn = createCallbackFcn(app, @Flow1_LabelFieldValueChanged, true);
            app.Flow1_LabelField.Value = '1';
            app.Flow2_LabelField = uieditfield(app.FlowMonitoringControlPanel, 'text');
            app.Flow2_LabelField.ValueChangedFcn = createCallbackFcn(app, @Flow2_LabelFieldValueChanged, true);
            app.Flow2_LabelField.Value = '2';
            app.Label_9 = uilabel(app.FlowMonitoringControlPanel);
            app.Panel = uipanel(app.MainTab);
            app.PressureAxes = uiaxes(app.Panel);
            title(app.PressureAxes, 'Pressure Data')
            xlabel(app.PressureAxes, 'Time (s)')
            ylabel(app.PressureAxes, 'Pressure (mmHg)')
            zlabel(app.PressureAxes, 'Z')
            app.FlowAxes = uiaxes(app.Panel);
            title(app.FlowAxes, 'Flow Data')
            xlabel(app.FlowAxes, 'Time(s)')
            ylabel(app.FlowAxes, 'Flow (L/min)')
            zlabel(app.FlowAxes, 'Z')
            app.HoldGraphsButton = uibutton(app.MainTab, 'state');
            app.HoldGraphsButton.Text = 'Hold Graphs';
            app.ConnectButton = uibutton(app.MainTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearDataButton = uibutton(app.MainTab, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ExportDataButton = uibutton(app.MainTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.RefreshListButton = uibutton(app.MainTab, 'push');
            app.RefreshListButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButtonPushed, true);
            app.RefreshListButton.Text = 'Refresh List';
            app.GraphOptionsLabel = uilabel(app.MainTab);
            app.GraphOptionsLabel.Text = 'Graph Options';
            app.COMPortLabel = uilabel(app.MainTab);
            app.COMPortLabel.Text = 'COM Port ';
            app.COMPortDropDown = uidropdown(app.MainTab);
            app.ConnectedLamp = uilamp(app.MainTab);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.SimulateDataCheckBox = uicheckbox(app.SettingsTab);
            app.SimulateDataCheckBox.Text = 'Simulate Data?';
            app.PressureValveControlPanel = uipanel(app.SettingsTab);
            app.PressureValveControlPanel.Title = 'Pressure Valve Control';
            app.MotorStepNumberEditFieldLabel = uilabel(app.PressureValveControlPanel);
            app.MotorStepNumberEditFieldLabel.Enable = 'off';
            app.MotorStepNumberEditFieldLabel.Text = 'Motor Step Number';
            app.MotorStepNumberEditField = uieditfield(app.PressureValveControlPanel, 'numeric');
            app.MotorStepNumberEditField.Limits = [0 200];
            app.MotorStepNumberEditField.RoundFractionalValues = 'on';
            app.MotorStepNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MotorStepNumberEditFieldValueChanged, true);
            app.MotorStepNumberEditField.Enable = 'off';
            app.ClosedSliderLabel = uilabel(app.PressureValveControlPanel);
            app.ClosedSliderLabel.Enable = 'off';
            app.ClosedSliderLabel.Text = '% Closed';
            app.ClosedSlider = uislider(app.PressureValveControlPanel);
            app.ClosedSlider.ValueChangedFcn = createCallbackFcn(app, @ClosedSliderValueChanged, true);
            app.ClosedSlider.Enable = 'off';
            app.PUMPONButton = uibutton(app.SettingsTab, 'push');
            app.PUMPONButton.Enable = 'off';
            app.PUMPONButton.Text = 'PUMP ON';
            app.PUMPOFFButton = uibutton(app.SettingsTab, 'push');
            app.PUMPOFFButton.Enable = 'off';
            app.PUMPOFFButton.Text = 'PUMP OFF';
            app.OPENVALVEButton = uibutton(app.SettingsTab, 'push');
            app.OPENVALVEButton.Enable = 'off';
            app.OPENVALVEButton.Text = 'OPEN VALVE';
            app.FlowPumpControlPanel = uipanel(app.SettingsTab);
            app.FlowPumpControlPanel.Title = 'Flow Pump Control';
            app.AnalogWriteValueEditFieldLabel = uilabel(app.FlowPumpControlPanel);
            app.AnalogWriteValueEditFieldLabel.Text = 'Analog Write Value';
            app.AnalogWriteValueEditField = uieditfield(app.FlowPumpControlPanel, 'numeric');
            app.AnalogWriteValueEditField.Limits = [0 255];
            app.AnalogWriteValueEditField.RoundFractionalValues = 'on';
            app.AnalogWriteValueEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogWriteValueEditFieldValueChanged, true);
            app.PumpPowerSliderLabel = uilabel(app.FlowPumpControlPanel);
            app.PumpPowerSliderLabel.Text = 'Pump Power  %';
            app.PumpPowerSlider = uislider(app.FlowPumpControlPanel);
            app.PumpPowerSlider.ValueChangedFcn = createCallbackFcn(app, @PumpPowerSliderValueChanged, true);
            app.ControlSwitchLabel = uilabel(app.FlowPumpControlPanel);
            app.ControlSwitchLabel.Text = 'Control';
            app.ControlSwitch = uiswitch(app.FlowPumpControlPanel, 'slider');
            app.ControlSwitch.Items = {'Manual', 'PID'};
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.ControlSwitch.Value = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCTA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end